classdef reactiontimeapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ProgressLabel      matlab.ui.control.Label
        StartButton        matlab.ui.control.Button
        VisualStimulus     matlab.ui.container.Panel
        StimulusTypeLabel  matlab.ui.control.Label
        ReactionTimeLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        currentStimulus
        arduinoObj
        hapticPin
        trialList
        currentTrialIndex
        keysPressed = {}         % Collect all keys pressed during trial
        reactionTimes = []       % Collect all RTs during trial
        
        % Persistent Timer Objects
        timerObj = []            % Inter-trial delay timer
        stimTimerObj = []        % Stimulus duration timer
        responseTimerObj = []    % Response window timer
        
        audioPlayer
        acceptingResponses = false
        stimulusEndTime
    end
    
    methods (Access = private)

        function clearTimer(app, timerProp)
            try
                if ~isempty(timerProp) && isvalid(timerProp)
                    stop(timerProp);
                    delete(timerProp);
                end
            catch
            end
        end

        function keyPressed(app, event)
            if ~app.acceptingResponses
                return;
            end
            % Record reaction time
            rt = seconds(datetime("now") - app.stimulusEndTime);
            % Store key + RT
            app.keysPressed{end+1} = event.Key;
            app.reactionTimes(end+1) = rt;
        end

        % --- STATE 1: Start Stimulus (Called by ITI Timer) ---
        function startStimulus(app)
            % Reset trial data
            app.keysPressed = {};
            app.reactionTimes = [];
            app.ReactionTimeLabel.Text = '';
            app.StimulusTypeLabel.Text = app.currentStimulus;
    
            % Disable responses
            app.acceptingResponses = false;
            app.UIFigure.WindowKeyPressFcn = @(src,event) app.keyPressed(event);
    
            % Stop/Delete previous audio safely
            if ~isempty(app.audioPlayer)
                try
                    stop(app.audioPlayer)
                end
            end
            app.audioPlayer = [];
    
            % Turn off visual/haptic initially
            app.VisualStimulus.Visible = 'off';
            if ~isempty(app.arduinoObj)
                writeDigitalPin(app.arduinoObj, app.hapticPin, 0);
            end
            drawnow; 
            
            % --- Present Stimulus ---
            try
                switch app.currentStimulus
                    case 'V'
                        app.VisualStimulus.Visible = 'on';
                    case 'A'
                        % Play 0.4s sound
                        y = sin(2*pi*440*(0:1/44100:0.4))'; 
                        app.audioPlayer = audioplayer(y, 44100);
                        play(app.audioPlayer);
                    case 'H'
                        if ~isempty(app.arduinoObj)
                            writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        end
                    case 'VA'
                        app.VisualStimulus.Visible = 'on';
                        y = sin(2*pi*440*(0:1/44100:0.4))';
                        app.audioPlayer = audioplayer(y, 44100);
                        play(app.audioPlayer);
                    case 'VH'
                        app.VisualStimulus.Visible = 'on';
                        if ~isempty(app.arduinoObj)
                            writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        end
                    case 'AH'
                        y = sin(2*pi*440*(0:1/44100:0.4))';
                        app.audioPlayer = audioplayer(y, 44100);
                        play(app.audioPlayer);
                        if ~isempty(app.arduinoObj)
                            writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        end
                    case 'VAH'
                        app.VisualStimulus.Visible = 'on';
                        y = sin(2*pi*440*(0:1/44100:0.4))';
                        app.audioPlayer = audioplayer(y, 44100);
                        play(app.audioPlayer);
                        if ~isempty(app.arduinoObj)
                            writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        end
                end
            catch ME
                warning(ME.identifier, 'Error starting stimulus: %s', ME.message);
            end
            drawnow; 

            % Start Stimulus Timer (0.5s)
            app.clearTimer(app.stimTimerObj);
            
            app.stimTimerObj = timer( ...
                'StartDelay', 0.5, ...
                'ExecutionMode', 'singleShot', ...
                'TimerFcn', @(~,~) app.beginResponseWindow());
            start(app.stimTimerObj);
        end
    
        % --- STATE 2: Begin Response (Called by Stimulus Timer) ---
        function beginResponseWindow(app)
            % Turn off stimuli
            try app.VisualStimulus.Visible = 'off'; end
            try if ~isempty(app.audioPlayer) && isplaying(app.audioPlayer), stop(app.audioPlayer); end; end
            try if ~isempty(app.arduinoObj), writeDigitalPin(app.arduinoObj, app.hapticPin, 0); end; end
            drawnow;

            % Accept responses
            app.acceptingResponses = true;
            app.stimulusEndTime = datetime("now");
    
            % Start Response Timer (1.5s)
            app.clearTimer(app.responseTimerObj);
            
            app.responseTimerObj = timer( ...
                'StartDelay', 1.5, ...
                'ExecutionMode', 'singleShot', ...
                'TimerFcn', @(~,~) app.endResponseWindow());
            start(app.responseTimerObj);
        end
    
        % --- STATE 3: End Response (Called by Response Timer) ---
        function endResponseWindow(app)
            app.acceptingResponses = false;
            try app.UIFigure.WindowKeyPressFcn = []; end
    
            % Compute FeltCorrectly
            correctKeysMap = struct('V',{'1'}, 'A',{'2'}, 'H',{'3'}, ...
                'VA',{'1','2'}, 'VH',{'1','3'}, 'AH',{'2','3'}, 'VAH',{'1','2','3'});
            correctKeys = correctKeysMap.(app.currentStimulus);
    
            if isequal(sort(app.keysPressed), sort(correctKeys))
                FeltCorrectly = 'YES';
            else
                FeltCorrectly = 'NO';
            end
    
            % Save Data
            if isempty(app.keysPressed)
                keysStr = ''; rtStr = '';
            else
                keysStr = strjoin(app.keysPressed, ',');
                rtStr = strjoin(arrayfun(@(x) sprintf('%.3f',x), app.reactionTimes, 'UniformOutput', false), ',');
            end
    
            data = table(datetime("now"), app.currentStimulus, keysStr, FeltCorrectly, rtStr, ...
                'VariableNames', {'Time','Stimulus','KeysPressed','FeltCorrectly','ReactionTimes'});
    
            csvFile = 'reaction_data.csv';
            try
                if isfile(csvFile)
                    writetable(data, csvFile, 'WriteMode', 'Append');
                else
                    writetable(data, csvFile);
                end
            catch ME
                warning(ME.identifier, 'Failed to write CSV: %s', ME.message);
            end
    
            % Move to next trial
            app.currentTrialIndex = app.currentTrialIndex + 1;
            
            % Schedule next trial safely
            app.clearTimer(app.timerObj);
            
            delay = 2 + rand()*3; % 2–5 seconds
            
            app.timerObj = timer( ...
                'StartDelay', delay, ...
                'ExecutionMode', 'singleShot', ...
                'TimerFcn', @(~,~) app.startNextTrial());
            
            start(app.timerObj);
        end
    
        % --- STATE 4: Next Trial / ITI (Called by End Response) ---
        function startNextTrial(app)
            % Check if experiment finished
            if app.currentTrialIndex > length(app.trialList)
                app.StimulusTypeLabel.Text = 'Experiment Complete!';
                app.ReactionTimeLabel.Text = '';
                app.VisualStimulus.Visible = 'off';
                app.ProgressLabel.Text = sprintf('Trial %d of %d', length(app.trialList), length(app.trialList));
                return;
            end
    
            % Reset UI
            app.acceptingResponses = false;
            app.VisualStimulus.Visible = 'off';
            app.ReactionTimeLabel.Text = '';
            app.StimulusTypeLabel.Text = ''; 
    
            app.currentStimulus = app.trialList{app.currentTrialIndex};
            app.ProgressLabel.Text = sprintf('Trial %d of %d', app.currentTrialIndex, length(app.trialList));
            
            % Random delay 1.5s - 3.5s
            delay = 1.5 + rand()*2;
    
            % Start ITI Timer
            app.clearTimer(app.timerObj);
            
            app.timerObj = timer( ...
                'StartDelay', delay, ...
                'ExecutionMode', 'singleShot', ...
                'TimerFcn', @(~,~) app.safeStartStimulus());
            start(app.timerObj);
        end
    
        function safeStartStimulus(app)
            try
                app.startStimulus();
            catch ME
                warning(ME.identifier, 'Error starting stimulus: %s', ME.message);
                app.currentTrialIndex = app.currentTrialIndex + 1;
                app.startNextTrial();
            end
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Visible = 'off';
            app.VisualStimulus.Visible = 'off';
            app.StimulusTypeLabel.Text = '';
            app.ReactionTimeLabel.Text = '';
            
            % Generate Trial List
            modes = {'V','A','H','VA','VH','AH','VAH'};
            app.trialList = repmat(modes,1,10);
            app.trialList = app.trialList(randperm(length(app.trialList)));
            
            app.currentTrialIndex = 1;
            
            app.startNextTrial();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReactionTimeLabel = uilabel(app.UIFigure);
            app.ReactionTimeLabel.Text = 'Reaction Time:';
            app.StimulusTypeLabel = uilabel(app.UIFigure);
            app.StimulusTypeLabel.Text = 'Stimulus Type';
            app.VisualStimulus = uipanel(app.UIFigure);
            app.VisualStimulus.Visible = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Trial: 0/70';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reactiontimeapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end